classdef Bassoon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BassoonUIFigure                matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        AcquisitionPanel               matlab.ui.container.Panel
        btnInitializeCamera            matlab.ui.control.Button
        brnInitializeDAQ               matlab.ui.control.Button
        RecordVideoButton              matlab.ui.control.Button
        btnStopRecording               matlab.ui.control.StateButton
        btnDisconnectCamera            matlab.ui.control.Button
        brnDisconnectDAQ               matlab.ui.control.Button
        CameraDropDownLabel            matlab.ui.control.Label
        cmbCameraType                  matlab.ui.control.DropDown
        DAQLabel                       matlab.ui.control.Label
        cmbCameraType_2                matlab.ui.control.DropDown
        VideoFilenameEditFieldLabel    matlab.ui.control.Label
        txtVideoFilename               matlab.ui.control.EditField
        PrevieweveryframeSpinnerLabel  matlab.ui.control.Label
        cmbPreviewEvery                matlab.ui.control.Spinner
        chkWatermark                   matlab.ui.control.CheckBox
        ExposuretimeLabel              matlab.ui.control.Label
        cmbExposure                    matlab.ui.control.Spinner
        lblTime                        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vidObj = [] % Camera device Object
        srcObj = [] % Camera source Object
        DAQ = [] % DAQ object
        TTL = false; % TTL pulse
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the dialog on main window
            posParent = get(0,'ScreenSize');
            pos = app.BassoonUIFigure.Position;
            pos(1) = ceil(posParent(1)+posParent(3)/2-pos(3)/2);
            pos(2) = ceil(posParent(2)+posParent(4)/2-pos(4)/2);
            app.BassoonUIFigure.Position = pos;                        
        end

        % Button pushed function: btnInitializeCamera
        function btnInitializeCameraButtonPushed(app, event)
            switch app.cmbCameraType.Value
                case 'gige'
                    %% Connect to the behavioral rig's camera                    
                    app.vidObj = videoinput('gige', 1, 'Mono8');
                    app.srcObj = app.vidObj.Source;
                    app.srcObj.DoubleRate_Enable = 'False'; % Record half framerate to avoid need for demodulation
                    app.srcObj.Window_W = 1024;
                    app.vidObj.ROIPosition = [0 0 662 1024]; %ROI code that was in the photonfocus file didn't work so I entered the numbers manually

                case 'linuxvideo'
                    %% Connect to Luca's laptop webcam                    
                    app.vidObj = videoinput('linuxvideo', 1, 'BGR24_640x480');          
                case 'xps webcam'
                    app.vidObj = webcam('OV01A');        
            end           
        end

        % Button pushed function: RecordVideoButton
        function RecordVideoButtonPushed(app, event)
            %% Record a stream of 100 frames from the camera to file
            
            switch app.cmbCameraType.Value
                case 'xps webcam'
                    
                otherwise
                    app.vidObj.FramesPerTrigger = inf;
                    app.vidObj.ReturnedColorSpace = 'rgb';
                    app.vidObj.FrameGrabInterval = 1;  % distance between captured frames 
                    app.srcObj.ExposureTime = app.cmbExposure.Value; %CHANGE
                    start(app.vidObj);
            end
            iFrame = 0;
            
            % Prepare DAQ
            if app.chkWatermark.Value
                n = ceil(d.Rate/10);
                app.TTL = false;
            end
            
            % Prepare vieo file writer
            aviObj = VideoWriter(app.txtVideoFilename.Value);   % Create a new AVI file
            open(aviObj);
            tic
            while app.btnStopRecording.Value == false            
                % Capture image I from camera
                switch app.cmbCameraType.Value
                     case 'xps webcam'
                         I = snapshot(app.vidObj);
                    otherwise
                        I = getsnapshot(app.vidObj); 
                end
                
                % Capture TTL value (true/false) from DAQ
                if app.chkWatermark.Value
                    data = read(d,n);
                    app.TTL = sum(data.Dev2_ai0) >0;
                    % Add a B/W square to top-left corner of image
                    I(1:25,1:25,:) = repmat(app.TTL*255,25,25,3);
                end
                
                % Preview every # frames the video
                F = im2frame(I);         % Convert I to a movie frame                               
                iFrame = iFrame+1;
                if mod(iFrame, app.cmbPreviewEvery.Value)==0
                    xlim(app.UIAxes, [1 size(I,2)]);
                    ylim(app.UIAxes, [1 size(I,1)]);
                    image(I, 'Parent', app.UIAxes);
                    iFrame = 0;
                end
                aviObj.writeVideo(F);    % Add the frame to the AVI file
            end
            app.lblTime.Text = num2str(toc);
            close(aviObj); % Close the AVI file
            switch app.cmbCameraType.Value
                case 'xps webcam'
                otherwise
                    stop(app.vidObj);  % Stop acquiring from camera 
            end
        end

        % Button pushed function: btnDisconnectCamera
        function btnDisconnectCameraButtonPushed(app, event)
            %% Disconnect the camera            
            delete(app.vidObj)           
        end

        % Button pushed function: brnInitializeDAQ
        function brnInitializeDAQButtonPushed(app, event)
            %% Initialize NI-USB CARD
            
            app.DAQ = daq("ni");
            addinput(app.DAQ, "Dev2", "ai0", "Voltage");
            start(d,"continuous");            
        end

        % Button pushed function: brnDisconnectDAQ
        function brnDisconnectDAQButtonPushed(app, event)
            if ~isempty(app.DAQ)
                stop(app.DAQ)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BassoonUIFigure = uifigure('Visible', 'off');
            app.BassoonUIFigure.Name = 'Bassoon';
            app.GridLayout = uigridlayout(app.BassoonUIFigure);
            app.AcquisitionPanel = uipanel(app.GridLayout);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.btnInitializeCamera = uibutton(app.AcquisitionPanel, 'push');
            app.btnInitializeCamera.ButtonPushedFcn = createCallbackFcn(app, @btnInitializeCameraButtonPushed, true);
            app.btnInitializeCamera.Text = 'Initialize';
            app.brnInitializeDAQ = uibutton(app.AcquisitionPanel, 'push');
            app.brnInitializeDAQ.ButtonPushedFcn = createCallbackFcn(app, @brnInitializeDAQButtonPushed, true);
            app.brnInitializeDAQ.Text = 'Initialize';
            app.RecordVideoButton = uibutton(app.AcquisitionPanel, 'push');
            app.RecordVideoButton.ButtonPushedFcn = createCallbackFcn(app, @RecordVideoButtonPushed, true);
            app.RecordVideoButton.Icon = '48px-Applications-multimedia.svg.png';
            app.RecordVideoButton.Text = {'Record'; 'Video'};
            app.btnStopRecording = uibutton(app.AcquisitionPanel, 'state');
            app.btnStopRecording.Icon = '48px-Dialog-STOP.svg.png';
            app.btnStopRecording.Text = {'Stop'; 'Recording'};
            app.btnDisconnectCamera = uibutton(app.AcquisitionPanel, 'push');
            app.btnDisconnectCamera.ButtonPushedFcn = createCallbackFcn(app, @btnDisconnectCameraButtonPushed, true);
            app.btnDisconnectCamera.Text = 'Disconnect';
            app.brnDisconnectDAQ = uibutton(app.AcquisitionPanel, 'push');
            app.brnDisconnectDAQ.ButtonPushedFcn = createCallbackFcn(app, @brnDisconnectDAQButtonPushed, true);
            app.brnDisconnectDAQ.Text = 'Disconnect';
            app.CameraDropDownLabel = uilabel(app.AcquisitionPanel);
            app.CameraDropDownLabel.Text = 'Camera';
            app.cmbCameraType = uidropdown(app.AcquisitionPanel);
            app.cmbCameraType.Items = {'gige', 'xps webcam', 'linuxvideo'};
            app.cmbCameraType.Value = 'gige';
            app.DAQLabel = uilabel(app.AcquisitionPanel);
            app.DAQLabel.Text = 'DAQ';
            app.cmbCameraType_2 = uidropdown(app.AcquisitionPanel);
            app.cmbCameraType_2.Items = {'NI USB'};
            app.cmbCameraType_2.Value = 'NI USB';
            app.VideoFilenameEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.VideoFilenameEditFieldLabel.Text = 'Video Filename';
            app.txtVideoFilename = uieditfield(app.AcquisitionPanel, 'text');
            app.txtVideoFilename.Value = 'myVideo.avi';
            app.PrevieweveryframeSpinnerLabel = uilabel(app.AcquisitionPanel);
            app.PrevieweveryframeSpinnerLabel.Text = 'Preview every # frame';
            app.cmbPreviewEvery = uispinner(app.AcquisitionPanel);
            app.cmbPreviewEvery.Limits = [1 Inf];
            app.cmbPreviewEvery.Value = 10;
            app.chkWatermark = uicheckbox(app.AcquisitionPanel);
            app.chkWatermark.Text = 'Watermark DAQ Trigger Into Video Stream';
            app.ExposuretimeLabel = uilabel(app.AcquisitionPanel);
            app.ExposuretimeLabel.Text = 'Exposure time';
            app.cmbExposure = uispinner(app.AcquisitionPanel);
            app.cmbExposure.Limits = [1 Inf];
            app.cmbExposure.Value = 2500;
            app.lblTime = uilabel(app.AcquisitionPanel);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            % Show the figure after all components are created
            app.BassoonUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bassoon

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BassoonUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.BassoonUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BassoonUIFigure)
        end
    end
end